classdef Kaan_IP_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Renk_DonusumLabel_2            matlab.ui.control.Label
        labLabel                       matlab.ui.control.Label
        uvLabel                        matlab.ui.control.Label
        hsvLabel                       matlab.ui.control.Label
        Renk_DonusumLabel              matlab.ui.control.Label
        ResimSecButton                 matlab.ui.control.Button
        kinciResimLabel                matlab.ui.control.Label
        SonuLabel                      matlab.ui.control.Label
        BirinciResimLabel              matlab.ui.control.Label
        SifirlaButton                  matlab.ui.control.Button
        IslemlerPanel                  matlab.ui.container.Panel
        FiltrelemeveGurultuIslemleriButtonGroup  matlab.ui.container.ButtonGroup
        FiltrelemeButtonGroup          matlab.ui.container.ButtonGroup
        MotionButton                   matlab.ui.control.Button
        GurultuTemizlemeButtonGroup    matlab.ui.container.ButtonGroup
        MeanFiltresiButton             matlab.ui.control.Button
        MedianFiltresiButton           matlab.ui.control.Button
        GurultuEklemeButtonGroup       matlab.ui.container.ButtonGroup
        SaltPepperButton               matlab.ui.control.Button
        KonvolusyonIslemiButtonGroup   matlab.ui.container.ButtonGroup
        MedianButton                   matlab.ui.control.Button
        KontrastIslemiButtonGroup      matlab.ui.container.ButtonGroup
        KontrastAzaltmaButton          matlab.ui.control.Button
        AritmetikIslemlerButtonGroup   matlab.ui.container.ButtonGroup
        IkinciResimEkleButton          matlab.ui.control.Button
        CikarmaButton                  matlab.ui.control.Button
        CarpmaButton                   matlab.ui.control.Button
        GoruntuUzerindeTemelIslemlerButtonGroup  matlab.ui.container.ButtonGroup
        UzaklastirmaButton             matlab.ui.control.Button
        YaklastirmaButton              matlab.ui.control.Button
        KirpmaButton                   matlab.ui.control.Button
        DondurmeButton                 matlab.ui.control.Button
        DonusumlerButtonGroup          matlab.ui.container.ButtonGroup
        RenkUzayiButton                matlab.ui.control.Button
        BinaryButton                   matlab.ui.control.Button
        GriButton                      matlab.ui.control.Button
        MorfolojikIslemlerButtonGroup  matlab.ui.container.ButtonGroup
        KapamaButton                   matlab.ui.control.Button
        AcmaButton                     matlab.ui.control.Button
        AsinmaButton                   matlab.ui.control.Button
        GenisletmeButton               matlab.ui.control.Button
        EsiklemeveKenarAlgilamaButtonGroup  matlab.ui.container.ButtonGroup
        CannyButton                    matlab.ui.control.Button
        CiftEsiklemeButton             matlab.ui.control.Button
        HistogramIslemleriButtonGroup  matlab.ui.container.ButtonGroup
        HistogramGermeButton           matlab.ui.control.Button
        HistogramGenisletmeButton      matlab.ui.control.Button
        GoruntuGirisHistogrami         matlab.ui.control.Button
        GORUNTUISLEMETEKNIKLERISIMULASYONULabel  matlab.ui.control.Label
        UIAxes_histogram               matlab.ui.control.UIAxes
        UIAxes_YCbCr                   matlab.ui.control.UIAxes
        UIAxes_yiq                     matlab.ui.control.UIAxes
        UIAxes_hsv                     matlab.ui.control.UIAxes
        UIAxes_ikinci                  matlab.ui.control.UIAxes
        UIAxes_sonuc                   matlab.ui.control.UIAxes
        UIAxes_birinci                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       img
       img2
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResimSecButton
        function ResimSecButtonPushed(app, event)
            [file, path] = uigetfile('*.*'); %herhangi bir dosya türünü seç
            if isequal(file,0) %seçimi iptal ettiyse
                figure(app.UIFigure); %eski normal yeri göstersin
                return;
            end
            app.img = imread(fullfile(path,file));
            imshow(app.img, 'Parent', app.UIAxes_birinci);
        end

        % Button pushed function: GriButton
        function GriButtonPushed(app, event)
           if isempty(app.img)
                error('Resim Yükleyin')
           else
           pre_griResim = app.img;
           griResim = KGriDonusum(pre_griResim);
           imshow(griResim, 'Parent', app.UIAxes_sonuc);
           end
        end

        % Button pushed function: BinaryButton
        function BinaryButtonPushed(app, event)
             if isempty(app.img)
                error('Resim Yükleyin')
            else
                prompt = {'Threshold Değerini Giriniz'};
                wintitle = 'THRESHOLD';      % pencere başlığı
                dims = [1 80];               % Pencere boyutları
            
                default_threshold = {'128'}; % Varsayılan değer

                thresholdValue = inputdlg(prompt, wintitle, dims, default_threshold);
                thresholdValue = str2double(thresholdValue{1});
            
                imgBinary = KBinaryDonusum(app.img,thresholdValue);
                imshow(imgBinary, 'Parent', app.UIAxes_sonuc);
             end
        end

        % Button pushed function: IkinciResimEkleButton
        function IkinciResimEkleButtonPushed(app, event)
            [file, path] = uigetfile('*.*'); %herhangi bir dosya türünü seç
            if isequal(file,0) %seçimi iptal ettiyse
                figure(app.UIFigure); %eski normal yeri göstersin
                return;
            end
            app.img2 = imread(fullfile(path,file));
            imshow(app.img2, 'Parent', app.UIAxes_ikinci);
        end

        % Button pushed function: RenkUzayiButton
        function RenkUzayiButtonPushed(app, event)
             if isempty(app.img)
                error('Resim Yükleyin')
            else
                pre_img = app.img;

                hsv_img = KHSV(pre_img);
                yiq_img = KYIQ(pre_img);
                ycbcr_img = KYCbCr(pre_img);
            
                imshow(hsv_img, 'Parent', app.UIAxes_hsv);
                imshow(yiq_img, 'Parent', app.UIAxes_yiq);
                imshow(ycbcr_img, 'Parent', app.UIAxes_YCbCr);
             end

        end

        % Button pushed function: SifirlaButton
        function SifirlaButtonPushed(app, event)
            cla(app.UIAxes_YCbCr);
            cla(app.UIAxes_yiq);
            cla(app.UIAxes_hsv);
            cla(app.UIAxes_birinci);
            cla(app.UIAxes_ikinci);
            cla(app.UIAxes_sonuc);
            cla(app.UIAxes_histogram);
            
            app.img = "";
            app.img2 = "";
        
        end

        % Button pushed function: GoruntuGirisHistogrami
        function GoruntuGirisHistogramiButtonPushed(app, event)
            if isempty(app.img)
                error('Resim Yükleyin')
            else
            histogram = KGirisHistogram(app.img);
            bar(app.UIAxes_histogram, histogram);
            end
        end

        % Button pushed function: HistogramGermeButton
        function HistogramGermeButtonPushed(app, event)
             if isempty(app.img)
                error('Resim Yükleyin')
            else
            
                prompt = {'Birinci Sınır Değerini Giriniz', 'İkinci Sınır Değerini Giriniz'};
                wintitle = 'Sınır Değerleri';
                boyut = [1 50];
                default_values = {'50', '200'};  % Varsayılan iki değer

                input = inputdlg(prompt, wintitle, boyut, default_values);

                sinir1 = str2double(input{1});
                sinir2 = str2double(input{2});
            
                gerilmis_resim = KHistogramGerme(app.img,sinir1,sinir2);
                gerilmis_resim_hist = reshape(gerilmis_resim, [], 1);

                imshow(gerilmis_resim, 'Parent', app.UIAxes_sonuc);
                bar(app.UIAxes_histogram, gerilmis_resim_hist);
             end
        end

        % Button pushed function: HistogramGenisletmeButton
        function HistogramGenisletmeButtonPushed(app, event)
             if isempty(app.img)
                error('Resim Yükleyin')
            else
                genislemis_resim = KHistogramGenisletme(app.img);
                genislemis_resim_hist= reshape(genislemis_resim,[],1);

                imshow(genislemis_resim, 'Parent', app.UIAxes_sonuc);
                bar(app.UIAxes_histogram, genislemis_resim_hist);
             end
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            if isempty(app.img)
                error('Resim Yükleyin')
            else
                prompt = {' Gürültü Yoğunluğunu Giriniz'};
                wintitle = 'Gürültü Yoğunluğu';      % pencere başlığı
                boyut = [1 50];                       % Pencere boyutları
                default_yogunluk = {'0.05'}; % Varsayılan değer

                yogunluk = inputdlg(prompt, wintitle, boyut, default_yogunluk);

                yogunluk = str2double(yogunluk{1}); % cell den double a çevirme
                gurultulu_resim = KSaltPepper(app.img,yogunluk);
                imshow(gurultulu_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: CiftEsiklemeButton
        function CiftEsiklemeButtonPushed(app, event)
            if isempty(app.img)
                error('Resim Yükleyin')
            else
                prompt = {'Alt Eşik Değerini Giriniz', 'Üst Eşik Değerini Giriniz'};
                wintitle = 'Eşik Değerleri';
                boyut = [1 50];
                input = inputdlg(prompt, wintitle, boyut);

                altesik = str2double(input{1});
                ustesik = str2double(input{2});

                esiklenmis_resim = KCiftEsikleme(app.img,altesik,ustesik);
                imshow(esiklenmis_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
             if isempty(app.img)
                error('Resim Yükleyin')
            else
            
                prompt = {'Alt Eşik Değerini Giriniz', 'Üst Eşik Değerini Giriniz' , 'Gauss Boyutunu Giriniz'   , 'Gauss Yoğunluğunu Giriniz'};
                wintitle = 'Canny Eşik Değerleri';
                boyut = [1 50];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                altesik = str2double(cevaplar{1});
                ustesik = str2double(cevaplar{2});
                gauss_boyut = str2double(cevaplar{3});
                gauss_sat = str2double(cevaplar{4});

                canny_resim = KCanny(app.img, altesik,ustesik,gauss_boyut,gauss_sat);
                imshow(canny_resim, 'Parent', app.UIAxes_sonuc);
             end
        end

        % Button pushed function: CarpmaButton
        function CarpmaButtonPushed(app, event)
            if isempty(app.img2) || isempty (app.img)
                error('2 Resim Yükleyiniz')
            else
                carpma_resim = KCarpma(app.img,app.img2);
                imshow(carpma_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: CikarmaButton
        function CikarmaButtonPushed(app, event)
            if isempty(app.img2) || isempty (app.img)
                error('2 Resim Yükleyiniz')
            else
                cikarma_resim = KCikarma(app.img,app.img2);
                imshow(cikarma_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: MeanFiltresiButton
        function MeanFiltresiButtonPushed(app, event)
            if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Kernel Boyutunu Giriniz', 'Gürültü Yoğunluğunu Giriniz'};
                wintitle = 'Mean Fonksiyonu Değerleri';
                boyut = [1 50];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                kernel_boyut = str2double(cevaplar{1});
                gurultu_yogunluk = str2double(cevaplar{2});
                mean_resim = KMeanTemizleme(app.img, kernel_boyut,gurultu_yogunluk );
                imshow(mean_resim, 'Parent', app.UIAxes_sonuc);
            end

        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
           if isempty(app.img)
                error('Resim Yükleyin')
           else
               prompt = {'Kernel Boyutunu Giriniz'};
               wintitle = 'Medan ile Konvolüsyon';
               boyut = [1 50];
               cevaplar = inputdlg(prompt, wintitle, boyut);

               kernel_boyut = str2double(cevaplar{1});
               median_resim = KMedian(app.img, kernel_boyut);

               imshow(median_resim, 'Parent', app.UIAxes_sonuc);
           end
        end

        % Button pushed function: MotionButton
        function MotionButtonPushed(app, event)
            if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Motion Yoğunluğunu Giriniz'};
                wintitle = 'Motion Değerleri';
                boyut = [1 50];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                motion_yogunluk = str2double(cevaplar{1});

                motion_resim = KMotion(app.img, motion_yogunluk);
                imshow(motion_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: MedianFiltresiButton
        function MedianFiltresiButtonPushed(app, event)
             if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Kernel Boyutunu Giriniz', 'Gürültü Yoğunluğunu Giriniz'};
                wintitle = 'Median Fonksiyonu Değerleri';
                boyut = [1 50];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                kernel_boyut = str2double(cevaplar{1});
                gurultu_yogunluk = str2double(cevaplar{2});
                median_resim = KMedianTemizleme(app.img, kernel_boyut,gurultu_yogunluk );
                imshow(median_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: KontrastAzaltmaButton
        function KontrastAzaltmaButtonPushed(app, event)
            if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Kontrast Azaltma Derecesini Giriniz (0.5 = %50 Azaltılır)'};
                wintitle = 'Kontrast Azaltma Değeri';
                boyut = [1 70];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                azaltma_yogunluk = str2double(cevaplar{1});
                kont_azaltilmis_resim = KKontrastAzaltma(app.img,azaltma_yogunluk);
                imshow(kont_azaltilmis_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: DondurmeButton
        function DondurmeButtonPushed(app, event)
            if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Döndürme Derecesini Giriniz (Saat Yönünde)'};
                wintitle = 'Döndürme Derecesş';
                boyut = [1 70];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                dondurme_aci = str2double(cevaplar{1});
                dondurulmus_resim = KDondur(app.img,dondurme_aci);
                imshow(dondurulmus_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: KirpmaButton
        function KirpmaButtonPushed(app, event)
             if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Satır', 'Sütun', 'Boy', 'En'};
                wintitle = 'Döndürme Derecesi';
                boyut = [1 10];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                satir = str2double(cevaplar{1});
                sutun = str2double(cevaplar{2});
                boy = str2double(cevaplar{3});
                en = str2double(cevaplar{4});

                kirpilmis_resim = KKirp(app.img,satir,sutun,boy,en);
                imshow(kirpilmis_resim, 'Parent', app.UIAxes_sonuc);
             end
        end

        % Button pushed function: YaklastirmaButton
        function YaklastirmaButtonPushed(app, event)
              if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Yaklaştırma Katsayısını Girin ( 2 = 2 kat yaklaştırma)'};
                wintitle = 'Yaklaştırma Derecesi';
                boyut = [1 70];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                yaklastirma_katsayi = str2double(cevaplar{1});
                yaklastirilmis_resim = KYaklastir(app.img,yaklastirma_katsayi);
                imshow(yaklastirilmis_resim, 'Parent', app.UIAxes_sonuc);
              end
        end

        % Button pushed function: UzaklastirmaButton
        function UzaklastirmaButtonPushed(app, event)
            if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Uzaklaştıma Katsayısını Girin ( 2 = 2 kat uzaklaştırma)'};
                wintitle = 'Uzaklaştırma Derecesi';
                boyut = [1 70];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                uzaklastirma_katsayi = str2double(cevaplar{1});
                uzaklastirilmis_resim = KUzaklastir(app.img,uzaklastirma_katsayi);
                imshow(uzaklastirilmis_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: KapamaButton
        function KapamaButtonPushed(app, event)
             if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Yapı Elemanının Bpyutunu Giriniz ( 3 = 3x3 lük matris )'};
                wintitle = 'Yapı Elemanı Boyutu';
                boyut = [1 70];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                yapieleman_boyut = str2double(cevaplar{1});
                kapanmis_resim = KKapama(app.img,yapieleman_boyut);
                imshow(kapanmis_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: AsinmaButton
        function AsinmaButtonPushed(app, event)
            if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Yapı Elemanının Bpyutunu Giriniz ( 3 = 3x3 lük matris )'};
                wintitle = 'Yapı Elemanı Boyutu';
                boyut = [1 70];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                yapieleman_boyut = str2double(cevaplar{1});
                asinmis_resim = KAsinma(app.img,yapieleman_boyut);
                imshow(asinmis_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: AcmaButton
        function AcmaButtonPushed(app, event)
            if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Yapı Elemanının Bpyutunu Giriniz ( 3 = 3x3 lük matris )'};
                wintitle = 'Yapı Elemanı Boyutu';
                boyut = [1 70];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                yapieleman_boyut = str2double(cevaplar{1});
                acilmis_resim = KAcma(app.img,yapieleman_boyut);
                imshow(acilmis_resim, 'Parent', app.UIAxes_sonuc);
            end
        end

        % Button pushed function: GenisletmeButton
        function GenisletmeButtonPushed(app, event)
            if isempty (app.img)
                error ('Resim Yükleyiniz');
            else
                prompt = {'Yapı Elemanının Bpyutunu Giriniz ( 3 = 3x3 lük matris )'};
                wintitle = 'Yapı Elemanı Boyutu';
                boyut = [1 70];
                cevaplar = inputdlg(prompt, wintitle, boyut);

                yapieleman_boyut = str2double(cevaplar{1});
                genislemiss_resim = KAcma(app.img,yapieleman_boyut);
                imshow(genislemiss_resim, 'Parent', app.UIAxes_sonuc);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_birinci = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_birinci, 'X')
            ylabel(app.UIAxes_birinci, 'Y')
            zlabel(app.UIAxes_birinci, 'Z')
            app.UIAxes_birinci.Visible = 'off';
            app.UIAxes_sonuc = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_sonuc, 'X')
            ylabel(app.UIAxes_sonuc, 'Y')
            zlabel(app.UIAxes_sonuc, 'Z')
            app.UIAxes_sonuc.Visible = 'off';
            app.UIAxes_ikinci = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_ikinci, 'X')
            ylabel(app.UIAxes_ikinci, 'Y')
            zlabel(app.UIAxes_ikinci, 'Z')
            app.UIAxes_ikinci.Visible = 'off';
            app.UIAxes_hsv = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_hsv, 'X')
            ylabel(app.UIAxes_hsv, 'Y')
            zlabel(app.UIAxes_hsv, 'Z')
            app.UIAxes_hsv.Visible = 'off';
            app.UIAxes_yiq = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_yiq, 'X')
            ylabel(app.UIAxes_yiq, 'Y')
            zlabel(app.UIAxes_yiq, 'Z')
            app.UIAxes_yiq.Visible = 'off';
            app.UIAxes_YCbCr = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_YCbCr, 'X')
            ylabel(app.UIAxes_YCbCr, 'Y')
            zlabel(app.UIAxes_YCbCr, 'Z')
            app.UIAxes_YCbCr.Visible = 'off';
            app.UIAxes_histogram = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_histogram, 'X')
            ylabel(app.UIAxes_histogram, 'Y')
            zlabel(app.UIAxes_histogram, 'Z')
            app.UIAxes_histogram.Visible = 'off';
            app.GORUNTUISLEMETEKNIKLERISIMULASYONULabel = uilabel(app.UIFigure);
            app.GORUNTUISLEMETEKNIKLERISIMULASYONULabel.Text = 'GÖRÜNTÜ İŞLEME TEKNİKLERİ SİMÜLASYONU';
            app.IslemlerPanel = uipanel(app.UIFigure);
            app.IslemlerPanel.Title = 'İŞLEMLER';
            app.HistogramIslemleriButtonGroup = uibuttongroup(app.IslemlerPanel);
            app.HistogramIslemleriButtonGroup.Title = 'Histogram İşlemleri';
            app.GoruntuGirisHistogrami = uibutton(app.HistogramIslemleriButtonGroup, 'push');
            app.GoruntuGirisHistogrami.ButtonPushedFcn = createCallbackFcn(app, @GoruntuGirisHistogramiButtonPushed, true);
            app.GoruntuGirisHistogrami.Text = 'Görüntü Giriş Histogramı';
            app.HistogramGenisletmeButton = uibutton(app.HistogramIslemleriButtonGroup, 'push');
            app.HistogramGenisletmeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramGenisletmeButtonPushed, true);
            app.HistogramGenisletmeButton.Text = 'Histogram Genişletme';
            app.HistogramGermeButton = uibutton(app.HistogramIslemleriButtonGroup, 'push');
            app.HistogramGermeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramGermeButtonPushed, true);
            app.HistogramGermeButton.Text = 'Histogram Germe';
            app.EsiklemeveKenarAlgilamaButtonGroup = uibuttongroup(app.IslemlerPanel);
            app.EsiklemeveKenarAlgilamaButtonGroup.Title = 'Eşikleme ve Kenar Algılama';
            app.CiftEsiklemeButton = uibutton(app.EsiklemeveKenarAlgilamaButtonGroup, 'push');
            app.CiftEsiklemeButton.ButtonPushedFcn = createCallbackFcn(app, @CiftEsiklemeButtonPushed, true);
            app.CiftEsiklemeButton.Text = 'Çift Eşikleme';
            app.CannyButton = uibutton(app.EsiklemeveKenarAlgilamaButtonGroup, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            app.MorfolojikIslemlerButtonGroup = uibuttongroup(app.IslemlerPanel);
            app.MorfolojikIslemlerButtonGroup.Title = 'Morfolojik İşlemler';
            app.GenisletmeButton = uibutton(app.MorfolojikIslemlerButtonGroup, 'push');
            app.GenisletmeButton.ButtonPushedFcn = createCallbackFcn(app, @GenisletmeButtonPushed, true);
            app.GenisletmeButton.Text = 'Genişletme';
            app.AsinmaButton = uibutton(app.MorfolojikIslemlerButtonGroup, 'push');
            app.AsinmaButton.ButtonPushedFcn = createCallbackFcn(app, @AsinmaButtonPushed, true);
            app.AsinmaButton.Text = 'Aşınma';
            app.AcmaButton = uibutton(app.MorfolojikIslemlerButtonGroup, 'push');
            app.AcmaButton.ButtonPushedFcn = createCallbackFcn(app, @AcmaButtonPushed, true);
            app.AcmaButton.Text = 'Açma';
            app.KapamaButton = uibutton(app.MorfolojikIslemlerButtonGroup, 'push');
            app.KapamaButton.ButtonPushedFcn = createCallbackFcn(app, @KapamaButtonPushed, true);
            app.KapamaButton.Text = 'Kapama';
            app.GoruntuUzerindeTemelIslemlerButtonGroup = uibuttongroup(app.IslemlerPanel);
            app.GoruntuUzerindeTemelIslemlerButtonGroup.Title = 'Görüntü Üzerinde Temel İşlemler';
            app.DonusumlerButtonGroup = uibuttongroup(app.GoruntuUzerindeTemelIslemlerButtonGroup);
            app.DonusumlerButtonGroup.Title = 'Dönüşümler';
            app.GriButton = uibutton(app.DonusumlerButtonGroup, 'push');
            app.GriButton.ButtonPushedFcn = createCallbackFcn(app, @GriButtonPushed, true);
            app.GriButton.Text = 'Gri';
            app.BinaryButton = uibutton(app.DonusumlerButtonGroup, 'push');
            app.BinaryButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryButtonPushed, true);
            app.BinaryButton.Text = 'Binary';
            app.RenkUzayiButton = uibutton(app.DonusumlerButtonGroup, 'push');
            app.RenkUzayiButton.ButtonPushedFcn = createCallbackFcn(app, @RenkUzayiButtonPushed, true);
            app.RenkUzayiButton.Text = 'Renk Uzayı';
            app.DondurmeButton = uibutton(app.GoruntuUzerindeTemelIslemlerButtonGroup, 'push');
            app.DondurmeButton.ButtonPushedFcn = createCallbackFcn(app, @DondurmeButtonPushed, true);
            app.DondurmeButton.Text = 'Döndürme';
            app.KirpmaButton = uibutton(app.GoruntuUzerindeTemelIslemlerButtonGroup, 'push');
            app.KirpmaButton.ButtonPushedFcn = createCallbackFcn(app, @KirpmaButtonPushed, true);
            app.KirpmaButton.Text = 'Kırpma';
            app.YaklastirmaButton = uibutton(app.GoruntuUzerindeTemelIslemlerButtonGroup, 'push');
            app.YaklastirmaButton.ButtonPushedFcn = createCallbackFcn(app, @YaklastirmaButtonPushed, true);
            app.YaklastirmaButton.Text = 'Yaklaştırma';
            app.UzaklastirmaButton = uibutton(app.GoruntuUzerindeTemelIslemlerButtonGroup, 'push');
            app.UzaklastirmaButton.ButtonPushedFcn = createCallbackFcn(app, @UzaklastirmaButtonPushed, true);
            app.UzaklastirmaButton.Text = 'Uzaklaştırma';
            app.AritmetikIslemlerButtonGroup = uibuttongroup(app.IslemlerPanel);
            app.AritmetikIslemlerButtonGroup.Title = 'Aritmetik İşlemler';
            app.CarpmaButton = uibutton(app.AritmetikIslemlerButtonGroup, 'push');
            app.CarpmaButton.ButtonPushedFcn = createCallbackFcn(app, @CarpmaButtonPushed, true);
            app.CarpmaButton.Text = 'Çarpma';
            app.CikarmaButton = uibutton(app.AritmetikIslemlerButtonGroup, 'push');
            app.CikarmaButton.ButtonPushedFcn = createCallbackFcn(app, @CikarmaButtonPushed, true);
            app.CikarmaButton.Text = 'Çıkarma';
            app.IkinciResimEkleButton = uibutton(app.AritmetikIslemlerButtonGroup, 'push');
            app.IkinciResimEkleButton.ButtonPushedFcn = createCallbackFcn(app, @IkinciResimEkleButtonPushed, true);
            app.IkinciResimEkleButton.Text = '2. RESMİ EKLE';
            app.KontrastIslemiButtonGroup = uibuttongroup(app.IslemlerPanel);
            app.KontrastIslemiButtonGroup.Title = 'Kontrast İşlemi';
            app.KontrastAzaltmaButton = uibutton(app.KontrastIslemiButtonGroup, 'push');
            app.KontrastAzaltmaButton.ButtonPushedFcn = createCallbackFcn(app, @KontrastAzaltmaButtonPushed, true);
            app.KontrastAzaltmaButton.Text = 'Kontrast Azaltma';
            app.KonvolusyonIslemiButtonGroup = uibuttongroup(app.IslemlerPanel);
            app.KonvolusyonIslemiButtonGroup.Title = 'Konvolüsyon İşlemi';
            app.MedianButton = uibutton(app.KonvolusyonIslemiButtonGroup, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.FiltrelemeveGurultuIslemleriButtonGroup = uibuttongroup(app.IslemlerPanel);
            app.FiltrelemeveGurultuIslemleriButtonGroup.Title = 'Filtreleme ve Gürültü İşlemleri';
            app.GurultuEklemeButtonGroup = uibuttongroup(app.FiltrelemeveGurultuIslemleriButtonGroup);
            app.GurultuEklemeButtonGroup.Title = 'Gürültü Ekleme';
            app.SaltPepperButton = uibutton(app.GurultuEklemeButtonGroup, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.GurultuTemizlemeButtonGroup = uibuttongroup(app.FiltrelemeveGurultuIslemleriButtonGroup);
            app.GurultuTemizlemeButtonGroup.Title = 'Gürültü Temizleme';
            app.MedianFiltresiButton = uibutton(app.GurultuTemizlemeButtonGroup, 'push');
            app.MedianFiltresiButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFiltresiButtonPushed, true);
            app.MedianFiltresiButton.Text = 'Median Filtresi';
            app.MeanFiltresiButton = uibutton(app.GurultuTemizlemeButtonGroup, 'push');
            app.MeanFiltresiButton.ButtonPushedFcn = createCallbackFcn(app, @MeanFiltresiButtonPushed, true);
            app.MeanFiltresiButton.Text = 'Mean Filtresi';
            app.FiltrelemeButtonGroup = uibuttongroup(app.FiltrelemeveGurultuIslemleriButtonGroup);
            app.FiltrelemeButtonGroup.Title = 'Filtreleme';
            app.MotionButton = uibutton(app.FiltrelemeButtonGroup, 'push');
            app.MotionButton.ButtonPushedFcn = createCallbackFcn(app, @MotionButtonPushed, true);
            app.MotionButton.Text = 'Motion';
            app.SifirlaButton = uibutton(app.UIFigure, 'push');
            app.SifirlaButton.ButtonPushedFcn = createCallbackFcn(app, @SifirlaButtonPushed, true);
            app.SifirlaButton.Text = 'Sıfırla';
            app.BirinciResimLabel = uilabel(app.UIFigure);
            app.BirinciResimLabel.Text = 'Birinci Resim :';
            app.SonuLabel = uilabel(app.UIFigure);
            app.SonuLabel.Text = 'Sonuç :';
            app.kinciResimLabel = uilabel(app.UIFigure);
            app.kinciResimLabel.Text = 'İkinci Resim :';
            app.ResimSecButton = uibutton(app.UIFigure, 'push');
            app.ResimSecButton.ButtonPushedFcn = createCallbackFcn(app, @ResimSecButtonPushed, true);
            app.ResimSecButton.Text = 'Resim Seç';
            app.Renk_DonusumLabel = uilabel(app.UIFigure);
            app.Renk_DonusumLabel.Text = 'Renk Dönüşümleri :';
            app.hsvLabel = uilabel(app.UIFigure);
            app.hsvLabel.Text = 'HSV';
            app.uvLabel = uilabel(app.UIFigure);
            app.uvLabel.Text = 'YIQ';
            app.labLabel = uilabel(app.UIFigure);
            app.labLabel.Text = 'YCbCr';
            app.Renk_DonusumLabel_2 = uilabel(app.UIFigure);
            app.Renk_DonusumLabel_2.Text = 'Histogram :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kaan_IP_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end